classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ResetviewButton               matlab.ui.control.Button
        Q_R_SButton                   matlab.ui.control.Button
        EnterButton                   matlab.ui.control.Button
        WindowstartEditFieldLabel     matlab.ui.control.Label
        WindowstartEditField          matlab.ui.control.NumericEditField
        WindowendEditFieldLabel       matlab.ui.control.Label
        WindowendEditField            matlab.ui.control.NumericEditField
        DiseasesButtonGroup           matlab.ui.container.ButtonGroup
        TachycardiaBradycardiaButton  matlab.ui.control.ToggleButton
        EctopicbeatButton             matlab.ui.control.ToggleButton
        FibrillationButton            matlab.ui.control.ToggleButton
        WelcometoECGApplicationLabel  matlab.ui.control.Label
        SpectogramButton              matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        LoadSignalButton              matlab.ui.control.Button
        info                          matlab.ui.control.TextArea
    end

    properties (Access = public)
        data;
        smw;
        Fs;
        Ts;
        N;
        time_axis;  
        Q_locs;
        S_locs;
        R_locs;
        window_start;
        window_end;
        text= 'please load the signal';
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.info.Value = app.text;
        end

        % Value changed function: WindowstartEditField
        function WindowstartEditFieldValueChanged(app, event)
            app.window_start = app.WindowstartEditField.Value;
        end

        % Button pushed function: LoadSignalButton
        function LoadSignalButtonPushed(app, event)
            [app.data,app.Fs,app.Ts,app.N,app.time_axis] = charger();
            plot(app.UIAxes,app.time_axis(app.Fs:4*app.Fs),app.data(app.Fs:4*app.Fs));
            app.text = 'signal loaded';
            app.info.Value = app.text;
        end

        % Value changed function: WindowendEditField
        function WindowendEditFieldValueChanged(app, event)
            app.window_end= app.WindowendEditField.Value;
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            app.UIAxes.cla;
            Q_locs_sec=app.Q_locs/app.Fs;
            S_locs_sec=app.S_locs/app.Fs;
            R_locs_sec=app.R_locs/app.Fs;
            [index_start, nb_peaks, data_window] = window(app.data,app.Fs,app.window_start,app.window_end,Q_locs_sec);
            plot(app.UIAxes,app.time_axis(app.window_start*app.Fs:app.window_end*app.Fs),data_window); 
            hold(app.UIAxes,'on');
            plot(app.UIAxes,Q_locs_sec(index_start:index_start+nb_peaks-1),app.data(app.Q_locs(index_start:index_start+nb_peaks-1)),'*')
            hold(app.UIAxes,'on');
            plot(app.UIAxes,S_locs_sec(index_start:index_start+nb_peaks-1),app.data(app.S_locs(index_start:index_start+nb_peaks-1)),'*')
            hold(app.UIAxes,'on');
            plot(app.UIAxes,R_locs_sec(index_start:index_start+nb_peaks-1),app.data(app.R_locs(index_start:index_start+nb_peaks-1)),'O')
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.time_axis(app.window_start*app.Fs:app.window_end*app.Fs),app.smw(app.window_start*app.Fs:app.window_end*app.Fs));            
            
        end

        % Button pushed function: Q_R_SButton
        function Q_R_SButtonPushed(app, event)
            app.text = 'waiting for QRS display';
            app.info.Value = app.text; 
            m=150;
            app.smw = Smw(app.data,app.Fs,app.N);
            [app.R_locs,app.Q_locs,app.S_locs] = R_Q_S_peaks(app.Fs,app.data,app.smw,m);
            Q_locs_sec=app.Q_locs/app.Fs;
            S_locs_sec=app.S_locs/app.Fs;
            R_locs_sec=app.R_locs/app.Fs;
            [index_start, nb_peaks, data_window] = window(app.data,app.Fs,1,4,Q_locs_sec);
            plot(app.UIAxes,app.time_axis(1*app.Fs:4*app.Fs),data_window); 
            hold(app.UIAxes,'on');
            plot(app.UIAxes,Q_locs_sec(index_start:index_start+nb_peaks-1),app.data(app.Q_locs(index_start:index_start+nb_peaks-1)),'*')
            hold(app.UIAxes,'on');
            plot(app.UIAxes,S_locs_sec(index_start:index_start+nb_peaks-1),app.data(app.S_locs(index_start:index_start+nb_peaks-1)),'*')
            hold(app.UIAxes,'on');
            plot(app.UIAxes,R_locs_sec(index_start:index_start+nb_peaks-1),app.data(app.R_locs(index_start:index_start+nb_peaks-1)),'O')
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.time_axis(app.Fs:4*app.Fs),app.smw(app.Fs:4*app.Fs));  
            app.text = 'QRS waves displayed';
            app.info.Value = app.text; 
        end

        % Button pushed function: ResetviewButton
        function ResetviewButtonPushed(app, event)
             app.UIAxes.cla;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ResetviewButton = uibutton(app.UIFigure, 'push');
            app.ResetviewButton.ButtonPushedFcn = createCallbackFcn(app, @ResetviewButtonPushed, true);
            app.ResetviewButton.Text = 'Reset view';
            app.Q_R_SButton = uibutton(app.UIFigure, 'push');
            app.Q_R_SButton.ButtonPushedFcn = createCallbackFcn(app, @Q_R_SButtonPushed, true);
            app.Q_R_SButton.Text = 'Q_R_S';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.WindowstartEditFieldLabel = uilabel(app.UIFigure);
            app.WindowstartEditFieldLabel.Text = 'Window start';
            app.WindowstartEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowstartEditField.ValueDisplayFormat = '%.0f';
            app.WindowstartEditField.ValueChangedFcn = createCallbackFcn(app, @WindowstartEditFieldValueChanged, true);
            app.WindowstartEditField.Value = 1;
            app.WindowendEditFieldLabel = uilabel(app.UIFigure);
            app.WindowendEditFieldLabel.Text = 'Window end';
            app.WindowendEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowendEditField.ValueDisplayFormat = '%.0f';
            app.WindowendEditField.ValueChangedFcn = createCallbackFcn(app, @WindowendEditFieldValueChanged, true);
            app.WindowendEditField.Value = 4;
            app.DiseasesButtonGroup = uibuttongroup(app.UIFigure);
            app.DiseasesButtonGroup.Title = 'Diseases';
            app.TachycardiaBradycardiaButton = uitogglebutton(app.DiseasesButtonGroup);
            app.TachycardiaBradycardiaButton.Text = 'Tachycardia/Bradycardia';
            app.TachycardiaBradycardiaButton.Value = true;
            app.EctopicbeatButton = uitogglebutton(app.DiseasesButtonGroup);
            app.EctopicbeatButton.Text = 'Ectopic beat';
            app.FibrillationButton = uitogglebutton(app.DiseasesButtonGroup);
            app.FibrillationButton.Text = 'Fibrillation';
            app.WelcometoECGApplicationLabel = uilabel(app.UIFigure);
            app.WelcometoECGApplicationLabel.Text = 'Welcome to ECG Application';
            app.SpectogramButton = uibutton(app.UIFigure, 'push');
            app.SpectogramButton.Text = 'Spectogram';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ElectroCardioGram Signal')
            xlabel(app.UIAxes, 'time in sec')
            ylabel(app.UIAxes, 'Signal')
            app.LoadSignalButton = uibutton(app.UIFigure, 'push');
            app.LoadSignalButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSignalButtonPushed, true);
            app.LoadSignalButton.Text = 'Load Signal';
            app.info = uitextarea(app.UIFigure);
            app.info.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = application

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end